classdef Mouse_forepaw_stimulator_v6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EndButton                       matlab.ui.control.Button
        PauseSwitch                     matlab.ui.control.Switch
        PauseSwitchLabel                matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        UpdateSpoutButton               matlab.ui.control.Button
        LEDLamp                         matlab.ui.control.Lamp
        LEDLampLabel                    matlab.ui.control.Label
        SpoutLamp_2                     matlab.ui.control.Lamp
        SpoutLamp_2Label                matlab.ui.control.Label
        RightBarGrabLamp_2              matlab.ui.control.Lamp
        RightBarGrabLamp_2Label         matlab.ui.control.Label
        LeftBarGrabLamp                 matlab.ui.control.Lamp
        LeftBarGrabLampLabel            matlab.ui.control.Label
        VoltageRampSwitchLabel          matlab.ui.control.Label
        VoltageRampSwitch               matlab.ui.control.Switch
        SensorSwitchLabel               matlab.ui.control.Label
        SensorSwitch                    matlab.ui.control.Switch
        BypassSwitchLabel               matlab.ui.control.Label
        BypassSwitch                    matlab.ui.control.Switch
        RewardPanel                     matlab.ui.container.Panel
        TrainingModeSwitch              matlab.ui.control.Switch
        TrainingModeSwitchLabel         matlab.ui.control.Label
        VacuumSolenoidSwitch            matlab.ui.control.Switch
        VacuumSolenoidSwitchLabel       matlab.ui.control.Label
        LiquidSolenoidSwitch            matlab.ui.control.Switch
        LiquidSolenoidSwitchLabel       matlab.ui.control.Label
        GiveRewardButton                matlab.ui.control.Button
        ExperimentPanel                 matlab.ui.container.Panel
        TargetBiasEditField             matlab.ui.control.NumericEditField
        TargetBiasEditFieldLabel        matlab.ui.control.Label
        PenaltyEditField                matlab.ui.control.NumericEditField
        PenaltyEditFieldLabel           matlab.ui.control.Label
        PrestimMaxEditField             matlab.ui.control.NumericEditField
        PrestimMaxEditFieldLabel        matlab.ui.control.Label
        PrestimMinEditField             matlab.ui.control.NumericEditField
        PrestimMinEditFieldLabel        matlab.ui.control.Label
        BlockEditField                  matlab.ui.control.NumericEditField
        BlockLabel                      matlab.ui.control.Label
        AdvanceonCorrectOnlyCheckBox    matlab.ui.control.CheckBox
        MouseIDEditField_2              matlab.ui.control.EditField
        MouseIDEditField_2Label         matlab.ui.control.Label
        PreLoadStimuliButton            matlab.ui.control.Button
        RewardhandleLabel               matlab.ui.control.Label
        CurrentTrialNoEditField_3       matlab.ui.control.NumericEditField
        CurrentTrialNoEditField_3Label  matlab.ui.control.Label
        LoadparameterfileButton         matlab.ui.control.Button
        MouseForepawStimulatorLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)

        %Local variables 
        mouseID = 0; %mouseID information
        trialNumber = 0; %number of trials per block 
        blockNumber = 0; %number of blocks for this session  
        totalTrials = 0; %number of total trials (trials x blocks) 
        outData = struct(); %used to export data for later analysis 
        expStartTime = NaN; %save the time the experiment begins 
        expEndTime = NaN; %save the time the experiment ends 
        expDuration = NaN; %save the duration of the entire experiment 
        expRunning = 0; %indicate whether the experiment is ongoing 
        pause_flag = 0; %pause
        workingdir = 'Z:\Chen\Metadata\';
        sessionName = "Metadata";
        ParameterData; %this will store the loaded parameter .mat dat 
        CurrentBlock = 0; %starting on block 0 and should update everytime you press on 'pre-load stimuli' 
        currDate = 0; %store the current date as a string for saving purposes 
        pauseFlag = 0; %0 = running, 1 = paused 

        %Scan Image 
        frameRate = NaN; %framerate of scanImage
        frameNumEstimate = NaN; %estimated total number of frames 
        ledFrameDelay = 5; %Delay 5 frames before stim start after led flash

        %NI-DAQ Parameters
        frameDetection = []; %variable for listener channels
        TTL_Times = []; %variable to hold TTL recieved times
        TTL_recieved = 0; %boolean flag to indicate a TTL was recieved
        frameCtrDev = NaN;
        CH_state = 0;
        piezoDriver = []; 
        piezoAudio = [];
        LeftcapSensPower = [];
        RightcapSensPower = [];
        lickSensPower = [];
        capListener = [];
        handleGraspedRight = 0;
        graspTimesRight = [];
        graspTimesRightOff = [];
        handleGraspedLeft = 0;
        graspTimesLeft = []; 
        graspTimesLeftOff = []; 
        lickTimes = [];
        lickSpout = 0; 
        fluidReward = NaN;
        fluidRewardSize = NaN;
        neuropixComm = NaN;
        sensorsInitialized = NaN;
        handleBypass = false;
        lh = [];
        commChannels = [];
        led = [];
        capListner; 
        andorSignal = []; %send TTL signal to andor camera for image collection 
        andorLED = []; %send TTL signal to andor camera to turn LED 
        andorCounter = []; %send TTL signal to begin counter 
        %will keep count of number of trials 

        %Stimulus 
        freq = 0; %frequency 
        amp = 0; %amplitude 
        fs = 20000; %sampling rate for stimulus delivery 
        stimulusPosition = NaN; %delivering stimulus to the left or right handle  
        stimulusDuration = 0; %length of the stimulus 
        stimVoltageRamp = NaN; %gradual increase/decrease in voltage over a specific period of time
        stimVoltageDur = NaN; %duration for Voltage ramp 
        stim_space = NaN; 
        iti_start = NaN; %inter-trial interval start 
        iti_end = NaN; %inter-trial interval end 
        iti_list = NaN; %list of inter-trial interval end as a function of trial number 
        stop_flag = 0; %off 
        preSI_start = NaN; %pre- stimulus interval start 
        preSI_end = NaN; %pre- stimulus interval end 
        led_times = NaN; %list of when the LED light was on 
        stim_times = NaN; %list of when the stimulus was on 
        attnAssign = NaN; %which handle would be considered the "attended handle" for that particular block 

        %Output items
        frameTimes = NaN;
        idx_file = NaN;

        %Reward parameters 
        rewardInitialize = 0; %initializes reward 
        lickTimeout = 0;
        vacLineOpenTime = 1; % time(sec) vac line is open to clear reward
        trainingMode = []; 
    end
    
    methods (Access = public)

        %detecting voltage signals from circuit 
        function TTL_detection(app, src, ~) %app object aka UI, source object to trigger the callback, event Data

            %read the latest data 
            [data, ~] = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");

            %difference in voltage threshold to be considered a 'lick' or
            %grasp
            vThresh = 4; 

            %detect lick events   
            %average mouse lick frequency: 100-150 ms

            lickVoltage = mean(data(end-49:end,2));  % average last 50 samples (10 ms)
            if lickVoltage > vThresh && ~app.lickSpout
                app.lickTimes = [app.lickTimes, GetSecs];
                app.lickSpout = 1;
                app.SpoutLamp_2.Color = [0,1,0];  % green = lick on
            elseif lickVoltage < 1 && app.lickSpout  % use a low threshold to detect release
                app.lickSpout = 0;
                app.SpoutLamp_2.Color = [1,0,0];  % red = no lick
            end

            %detect bar grasp 
            % Average over last 50 samples (~10 ms) to reduce noise
            leftVoltage = mean(data(end-49:end, 3));   % Channel 3 = left bar
            rightVoltage = mean(data(end-49:end, 1));  % Channel 1 = right bar
            
            vThresh_grasp = 3;  % Adjust this based on your actual signal (e.g. 0–5V)
            
            % --- LEFT BAR ---
            if leftVoltage > vThresh_grasp && ~app.handleGraspedLeft
                app.graspTimesLeft = [app.graspTimesLeft, GetSecs];
                app.handleGraspedLeft = 1;
                app.LeftBarGrabLamp.Color = [0, 1, 0];  % Green when grasped
            elseif leftVoltage < vThresh_grasp - 1 && app.handleGraspedLeft
                app.graspTimesLeftOff = [app.graspTimesLeftOff, GetSecs];
                app.handleGraspedLeft = 0;
                app.LeftBarGrabLamp.Color = [1, 0, 0];  % Red when released
            end
            
            % --- RIGHT BAR ---
            if rightVoltage > vThresh_grasp && ~app.handleGraspedRight
                app.graspTimesRight = [app.graspTimesRight; GetSecs];
                app.handleGraspedRight = 1;
                app.RightBarGrabLamp_2.Color = [0, 1, 0];
            elseif rightVoltage < vThresh_grasp - 1 && app.handleGraspedRight
                app.graspTimesRightOff = [app.graspTimesRightOff; GetSecs];
                app.handleGraspedRight = 0;
                app.RightBarGrabLamp_2.Color = [1, 0, 0];
            end
       
            % --- OLD CODE ---
            % lickData = mean(reshape(data(:,2),50,[]),1); %collecting an average across 50 samples aka 10 ms
            % lickDiff = diff(lickData); %calculating the difference between each 50-sample chunk aka every 10 ms
            % 
            % if any(lickDiff > vThresh) && ~app.lickSpout %if the difference is greater then the threshold 
            %     app.lickTimes = [app.lickTimes, GetSecs]; %storing all lick times continuously into app.lickTimes  
            %     app.lickSpout = 1; %Update lick status 
            %     app.SpoutLamp_2.Color = [0,1,0]; %change lamp color to green
            % elseif any(lickDiff < -vThresh) && app.lickSpout 
            %     app.lickSpout = 0; 
            %     app.SpoutLamp_2.Color = [1, 0, 0]; %change lamp color to red 
            % end 
            

            % %detect bar grasp
            % %average mouse grasp frequency: 100 - 300 ms 
            % 
            % leftBarData = mean(reshape(data(:,3),50,[]),1); %collecting an average across 50 samples aka 10 ms 
            % leftDiff = diff(leftBarData); %calculating the difference between each 50-sample chunk aka every 10 ms 
            % 
            % rightBarData = mean(reshape(data(:,1),50,[]),1); %collecting an average across 50 samples aka 10 ms 
            % rightDiff = diff(rightBarData); %calculating the difference between each 50-sample chunk aka every 10 ms
            % 
            % %Left bar grasp detection 
            % if any(leftDiff > vThresh) && ~app.handleGraspedLeft %if the difference is greater then the threshold 
            %     app.graspTimesLeft = [app.graspTimesLeft, GetSecs]; %store left handle grasp times 
            %     app.handleGraspedLeft = 1; %Update grasped status 
            %     app.LeftBarGrabLamp.Color = [0, 1, 0]; %change lamp color to green 
            % elseif any(leftDiff < -vThresh) && app.handleGraspedLeft 
            %     app.graspTimesLeftOff = [app.graspTimesLeftOff, GetSecs]; %store left handle release times 
            %     app.handleGraspedLeft = 0; %Update grasped status 
            %     app.LeftBarGrabLamp.Color = [1, 0, 0]; %change lamp color to red
            % end 
            % 
            %  %Right bar grasp detection 
            % if any(rightDiff > vThresh) && ~app.handleGraspedRight  %if the difference is greater then the threshold 
            %     app.graspTimesRight = [app.graspTimesRight; GetSecs]; %store right handle grasp times 
            %     app.handleGraspedRight = 1; %Update grasped status 
            %     app.RightBarGrabLamp_2.Color = [0, 1, 0]; %change lamp color to green 
            % elseif any(rightDiff < -vThresh) && app.handleGraspedRight 
            %     app.graspTimesRightOff = [app.graspTimesRightOff; GetSecs]; %store right handle release times 
            %     app.handleGraspedRight = 0; %Update grasped status 
            %     app.RightBarGrabLamp_2.Color = [1, 0, 0]; %change lamp color to red
            % end 

        end 
    end 

    methods (Access = private)

        function betterPause(~,n)
            startTime = GetSecs;
            while (GetSecs - startTime) < n
                pause(0);
            end
        end
        
        function results = initializeReward(app)
            %set up channels to control solenoids for fluid delivery:
            %liquid = 5 
            %vacuum = 6
            app.fluidReward = daq("ni"); 
            addoutput(app.fluidReward, "Dev1", "Port1/Line5", "Digital"); %liquid solenoid 
            addoutput(app.fluidReward, "Dev1", "Port1/Line6", "Digital"); %vacuum solenoid 
            write(app.fluidReward, [0 0]); %keep solenoids closed 
            app.rewardInitialize = 1;
            pause(0.01);
        end
        
        function results = deliverReward(app)
            write(app.fluidReward, [1 0]); %[fluid ON, vacuum OFF]
            pause(app.ParameterData.reward_size);
            write(app.fluidReward, [0 0]); %[fluid OFF, vacuum OFF] 
            pause(app.ParameterData.lick_timeout); 
            write(app.fluidReward, [0 1]); %[fluid OFF, vacuum ON]
            pause(app.ParameterData.vac_time);
            write(app.fluidReward, [0 0]); %[fluid OFF, vacuum OFF] 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function MouseIDEditFieldValueChanged(app, event)
            app.mouseID = app.MouseIDEditField.Value;
        end

        % Callback function
        function SwitchValueChanged(app, event)
            app.experimentType = app.PauseSwitch.Value;
        end

        % Callback function
        function ITIsEditFieldValueChanged(app, event)
            app.iti_start = app.ITIsEditField.Value;
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            app.iti_end = app.MouseIDEditField_2.Value;
        end

        % Callback function
        function FrequencyEditFieldValueChanged(app, event)
            app.freq = app.FrequencyEditField.Value;
        end

        % Callback function
        function AmplitudeEditFieldValueChanged(app, event)
            app.amp = app.AmplitudeEditField.Value;
        end

        % Callback function
        function Switch_2ValueChanged(app, event)
            app.stimulusPosition = app.Switch_2.Value; 
        end

        % Button pushed function: PreLoadStimuliButton
        function PreLoadStimuliButtonPushed(app, event)

            %Create the piezo stimulus file
            %Get local copies of variables
            %Reading from loaded .mat file 
            app.trialNumber = app.ParameterData.numTrials; %number of trials per block 
            stim_dur = app.ParameterData.stim_dur; %duration length in second
            app.amp = app.ParameterData.amplitude; %amplitude value(s)
            app.freq = app.ParameterData.frequency; %frequency value(s)
            app.stim_space = cell(app.ParameterData.totalTrials,1);
            
            %Check to make sure the length of freq = amp
            if length(app.freq) ~= length(app.amp)
                error('Frequency and amplitude lists must be the same length');
            end 

            %Tile to match trial numbers in this block 
            num_pairs = length(app.freq); 
            repeatFactor = ceil(app.trialNumber / num_pairs); 
            freqsFull = repmat(app.freq(:), repeatFactor, 1); %repeat the number of combinations to cover all trials
            ampsFull = repmat(app.amp(:), repeatFactor, 1); %repeat the number of combinations to cover all trials
            freqsFull = freqsFull(1:app.trialNumber); %trim in case the vector is too long 
            ampsFull = ampsFull(1:app.trialNumber); %trim in case the vector is too long

            %Shuffle pair indices 
            shuffleIdx = randperm(app.trialNumber); 
            freqsShuffled = freqsFull(shuffleIdx); 
            ampsShuffled = ampsFull(shuffleIdx); 

            %set up outputs
            all_durs = zeros(app.trialNumber,1);
            all_freqs = zeros(app.trialNumber,1);
            all_amps = zeros(app.trialNumber,1);
            app.stim_times = zeros(app.trialNumber,1);
            app.led_times = zeros(app.trialNumber,1);
            
            %Update Block Number
            if ~isprop(app, 'CurrentBlock') || isempty(app.CurrentBlock)
                app.CurrentBlock = 1;
            else
                app.CurrentBlock = app.CurrentBlock + 1;
            end
            app.BlockEditField.Value = app.CurrentBlock;

            %Create ITI 
            %2 - 4 seconds randomly 
            app.iti_start = app.ParameterData.itiMin;
            app.iti_end = app.ParameterData.itiMax;
            ITI_vector = app.iti_start + (app.iti_end - app.iti_start) * rand(app.trialNumber, 1); 
            
            %Create stim space (DURATION)
            if app.VoltageRampSwitch == 1
                stim_dur = stim_dur + 2*(stim_dur*0.1); %account for both ramp up and ramp down
                %in seconds 
                %extends the stimulus duration 
                %using 10% of the total stimulus duration for ramp up and
                %ramp down 
            end
            
            app.fs = 20000; 
         
            %Loop through all of the trials to set up stimulus space 
            for i = 1 : app.trialNumber
                app.stim_space{i,1} = zeros(stim_dur * app.fs); %sample points per second  
                all_durs(i) = stim_dur; %in seconds 
            end

            %Create time vector from 0 to stim_dur
            t = 0:1/app.fs:stim_dur - 1/app.fs; 
            
            for i = 1:app.trialNumber 
                currentFreq = freqsShuffled(i);
                currentAmp = ampsShuffled(i); 

                %Create the stimulus waveform 
                currentStim = sin(2 * pi * currentFreq * t); 

                if app.VoltageRampSwitch == 1 

                    % --- Voltage ramp is ON --- 
                    
                    %Define ramp duration as 10% of the stimulus duration 
                    rampDur = stim_dur * 0.1;  
                    t1 = round(rampDur * app.fs); %ramp up/down length in samples 
                    
                    %Calculate steady-state length
                    %Number of sample points for the entire stimulus (including the ramp) 
                    steady_len = length(currentStim) - 2*t1; 
                    
                    %Create full envelope 
                    rampUp = linspace(0, 1, t1); 
                    steady = ones(1, steady_len); 
                    rampDown = linspace(1, 0, t1); 
                    envelope = [rampUp, steady, rampDown]; 
                    envelope = envelope(1:length(currentStim)); 

                    %Apply envelope 
                    currentStim = currentStim .*envelope * currentAmp; 
                else 
                    % --- Voltage ramp is OFF --- 

                    currentStim = currentStim .* currentAmp; %apply constant amplitude to waveforms 
                end 

                %Save waveform to stim_space  
                app.stim_space{i,1} = currentStim; 

                %Save freq and amp
                all_freqs(i) = currentFreq; 
                all_amps(i) = currentAmp; 
            end 
            
            %Pre-stimulus interval 
            %1 - 2 seconds randomly 

            preStim_intStart = app.PrestimMinEditField.Value; %in seconds 
            preStim_intEnd = app.PrestimMaxEditField.Value; % in seconds 

            preStim_intVector = preStim_intStart + (preStim_intEnd - preStim_intStart) * rand(app.trialNumber, 1); 

            %Calculate an estimate total experiment duration
            exp_duration = sum(preStim_intVector)+sum(ITI_vector)+sum(all_durs);
           
            disp("Stimulus file loaded successfully...")
            disp(exp_duration)

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
         
            %Send signal to andor camera to turn on LED 
            write(app.andorLED, 1); %send TTL to andor to turn on LED
            pause(0.01); %Wait 10 ms
            disp('LED light turn on'); 
            app.LEDLamp.Color = 'green';  

            %Send signal to andor camera to start collecting images
            app.andorSignal = daq("ni");
            addoutput(app.andorSignal, "Dev1", "Port1/Line7", "Digital"); %start the camera 
            write(app.andorSignal, 1); %send TTL to camera
            pause(0.01); %Wait 10 ms
            disp('TTL sent to camera');
            disp('Beginning image collection');

            %Initialize andor counter for counting trials 
            app.andorCounter = daq("ni"); 
            addoutput(app.andorCounter, "Dev1", "Port2/Line4", "Digital"); 
            app.StatusLamp.Color = 'green';
            
            %Creating output file
            %Output the saved data (out_times and out_events)
            app.outData = [];
            disp('Identifying directory to save mouse data...');
            
            % Check if mouse directory exists, create it if necessary
            app.mouseID = app.ParameterData.mouseID; 

            if isfolder([app.workingdir '\' app.ParameterData.mouseID]) ~= 1
                mkdir([app.workingdir '\' app.ParameterData.mouseID]);
            end
            
            app.currDate = datestr(date,2);
            app.currDate(strfind(app.currDate, '/')) = '_';  % Format date as 'yyyy_mm_dd'
            
            % Create session-specific directory if it doesn't exist
            if isfolder([app.workingdir '\' app.ParameterData.mouseID '\' app.currDate]) ~= 1
                mkdir([app.workingdir '\' app.ParameterData.mouseID '\' app.currDate]);
            end
            
            % Get the current file directory
            curDirFile = dir([app.workingdir '\' app.ParameterData.mouseID '\' app.currDate]);
            curDirFile = {curDirFile.name};
            
            % Initialize file index
            app.idx_file = 0;
            for ff = 1 : length(curDirFile)
                tempFile = curDirFile{ff};
                if contains(tempFile, [app.ParameterData.mouseID '_' app.sessionName])
                    app.idx_file = app.idx_file + 1;
                end
            end
            
            % Format file index for consistency
            if app.idx_file < 10
                app.idx_file = ['00' num2str(app.idx_file)];
            elseif app.idx_file >= 10 && app.idx_file < 100
                app.idx_file = ['0' num2str(app.idx_file)];
            else
                app.idx_file = num2str(app.idx_file);
            end
            
            sessionResults = struct(); %creating structure 

            %start the experiment

            %Load in parameters from .mat file 
            responseWindow_Dur = app.ParameterData.responseWindow_dur; %in seconds 
            vibration_Dur = app.ParameterData.stim_dur; %in seconds 
            preStim_intStart = app.ParameterData.prestimMin; %in seconds
            preStim_intEnd = app.ParameterData.prestimMax; %in seconds
            noLickWindow = app.ParameterData.noLickWindow; %in seconds 
            penalty_Dur = app.ParameterData.penalty_dur; %in seconds 
            app.iti_start = app.ParameterData.itiMin; %in seconds
            app.iti_end = app.ParameterData.itiMax; %in seconds 
            numTrials = app.trialNumber; %for this block (which will theoretically be the same for each block) 
            targetBias = app.ParameterData.targetBias; %trials bias for the attended side 

            %Generate balanced and randomized left/right trials 50% each 
            %This would be for each block seperately 
           
            attended_temp = app.attnAssign(app.CurrentBlock); %can be either 0 or 1 
            unattended_temp = 1 - attended_temp; 

            numAttended = ceil(numTrials * targetBias); 
            numUnattended = numTrials - numAttended; 

            %Create vector with correct propotions 
            sides = [repmat(attended_temp, 1, numAttended), repmat(unattended_temp, 1, numUnattended)];

            %Randomize 
            stimAssign = sides(randperm(numTrials)); 
            
            app.stop_flag = 0; 
            disp('Starting experiment ...');
            app.expRunning = 1; 
            
            %LED flash to indicate the start of a new block 
            %LED flash 3 times 
            app.led_times(1,1) = GetSecs; %record time of the start of a new block  
            app.expStartTime = GetSecs; %record start time of the session 
            sessionResults.expStartTime = app.expStartTime; 

            for n = 1:3 %3 flashes 
                writeDigitalPin(app.commChannels, 'D5', 1) %LED on 
                %100 ms pause 
                startTime = GetSecs;   
                while (GetSecs - startTime) < 0.1
                    pause(0)
                end 
                writeDigitalPin(app.commChannels, 'D5', 0); %LED off 
                %100 ms pause 
                startTime = GetSecs; 
                while (GetSecs - startTime) < 0.1 
                    pause(0)
                end 
            end

            %Main trial loop 
            %Press button once for each new block 
            if app.rewardInitialize == 0 || isempty(app.fluidReward) || ~isvalid(app.fluidReward)
                disp('Reward not initialized or invalid — calling initializeReward');
                app.initializeReward();
            end

            for ii = 1:app.ParameterData.numTrials 
                 repeatTrial = true; 
                    
                 if app.pauseFlag 
                     disp('Experiment paused ...'); 
                 end 
                
                while isvalid(app) && ishandle(app.UIFigure) && app.pauseFlag %check to see if the pause button is on
                    %0 = running 
                    %1 = paused 
                    %current trial will finish before experiment is paused 
                    pause(0.1); 
                end 

                while repeatTrial 
                    % Check for stop flag
                    if app.stop_flag == 1
                        disp("Experiment stopped manually");
                        write(app.LeftcapSensPower,0);
                        write(app.RightcapSensPower,0);
                        write(app.lickSensPower,0);
                        return;  % Stop entire function
                    end
            
                    % Determine which handle is attended
                    if app.attnAssign(app.BlockEditField.Value) == 0
                        app.RewardhandleLabel.Text = 'Attend Left';
                        attendedSide = 'left';
                        unattendedSide = 'right';
                        sessionResults.AttendedSide{ii} = attendedSide; 
                    else
                        app.RewardhandleLabel.Text = 'Attend Right';
                        attendedSide = 'right';
                        unattendedSide = 'left';
                        sessionResults.AttendedSide{ii} = attendedSide;
                    end

                    %Display current trial no.
                    app.CurrentTrialNoEditField_3.Value = ii;
                    sessionResults.TrialNo{ii} = ii; 

                    %Prepare for stim delivery 
                    if isnan(app.sensorsInitialized)
                        error('Please Initialize Sensors Before Proceeding ...'); 
                    end 
                                            
                    %Wait for bar grasp
                    disp(['Trial ' num2str(ii) ' - Waiting for grasp...']);
                    while true 
                        pause(0.001); 

                        %exit loop if app is deleted or closing 
                        if ~isvalid(app)
                            disp('App closed during trial - exiting grasp check.'); 
                            break; 
                        end 

                        %exit loop if both handles are grasped 
                        if app.handleGraspedLeft && app.handleGraspedRight 
                            break; 
                        end 

                        %exit loop if bypass is triggered 
                        if app.handleBypass 
                            disp('Bypass activated - continuing without bar grasp.'); 
                            break; 
                        end 
                    end 
                    
                    %Collect trial start time 
                    trialStartTime = GetSecs; %record trial start time 
                    sessionResults.trialStartTimes{ii} = trialStartTime; %save trial start time 

                    %Pre-stimulus random interval
                    preStim_vector = rand() * (preStim_intEnd - preStim_intStart) + preStim_intStart;
                    disp(['Pre-stim interval: ' num2str(preStim_vector) ' s']);
                    sessionResults.PreStimInterval{ii} = preStim_vector; 
                    t0 = GetSecs;
                    earlyLick = false;

                    while true 
                        t0 = GetSecs; 
                        while GetSecs - t0 < preStim_vector 
                            if isvalid(app) && isvalid(app.SpoutLamp_2) && isequal(app.SpoutLamp_2.Color, [0 1 0])
                                disp("Early lick during pre-stim");
                                earlyLick = true;
                                sessionResults.earlyLick{ii} = true; 
                                break;
                            end 
    
                            %Check for continuous grasping 
                            if ~(app.handleGraspedLeft && app.handleGraspedRight) && ~app.handleBypass 
                                disp('Grasp lost during pre-stim interval - pausing and waiting to re-grasp ...'); 
                                
                                %Wait until mouse grasps both bars again 
                                while ~(app.handleGraspedLeft && app.handleGraspedRight) && ~app.handleBypass 
                                    pause(0.001); 
                                end 

                                %Mouse has re-grasped! Reset pre-stim timer
                                %and random interval 
                                preStim_vector = rand() * (preStim_intEnd - preStim_intStart) + preStim_intStart;
                                disp(['New Pre-stim interval after re-grasp: ' num2str(preStim_vector) ' s']); 
                                sessionResults.PreStimInterval{ii} = preStim_vector; 
                                t0 = GetSecs; %reset timer to now 
                                earlyLick = false;
                                continue; 
                            end 

                            pause(0.001)
                        end 

                        %Break outer loop if no early lick 
                        break; 

                    end 
                    
                    %Early lick penalty
                    if earlyLick 
                        disp('Applying penalty for early lick');
                        % Store detailed data
                        sessionResults.StimulusSide{ii} = NaN; %Stimulus side not relevant for early lick
                        sessionResults.AttendedSide{ii} = NaN; %Attended side not relevant for early lick 
                        sessionResults.ResponseTime{ii} = NaN;
                        sessionResults.Outcome{ii} = 'early lick';
                        sessionResults.earlyLick{ii} = true;
                        pause(penalty_Dur);
                        iti = rand() * (app.iti_end - app.iti_start) + app.iti_start; 
                        pause(iti);
                        continue; 
                    end
            
                    %Determine stimulus side string 
                    currentStimSide = stimAssign(ii);
                    sessionResults.StimulusSide{ii} = currentStimSide;  % Store the side for each trial
                    if currentStimSide == 0
                        stimSideStr = 'left';
                        disp('Delivering vibration to LEFT handle');
                        stim_data = [zeros(size(app.stim_space{ii,1})), app.stim_space{ii,1}];
                    else
                        stimSideStr = 'right';
                        disp('Delivering vibration to RIGHT handle');
                        stim_data = [app.stim_space{ii,1}, zeros(size(app.stim_space{ii,1}))];
                    end
                    
                    %Store if this was the attended side
                    isAttended = strcmp(attendedSide, stimSideStr);
                    sessionResults.isAttended{ii} = isAttended;
                    
                    %Initialize response time variable (always define this
                    %before using) 

                    temp = NaN; 
                    
                    %Turn on LED and TTL
                    writeDigitalPin(app.commChannels, 'D5', 1); 
                    write(app.andorCounter, 1);
                    app.led_times(ii,1) = GetSecs;
                    sessionResults.ledTimes{ii} = app.led_times(ii,1);
                    
                    %Short delay
                    timePreITI = GetSecs;
                    while GetSecs < timePreITI + 0.01
                        pause(0);
                    end
                    
                    %Turn off LED and TTL
                    writeDigitalPin(app.commChannels, 'D5', 0);
                    write(app.andorCounter, 0);
                    
                    %Check one last time to make sure the mouse is grabbing
                    %before you deliver the vibration .... 
                    if ~(app.handleGraspedLeft && app.handleGraspedRight) && ~app.handleBypass 
                        disp('Grasp lost - pausing and waiting to re-grasp ...'); 
                        
                        %Wait until mouse grasps both bars again 
                        while ~(app.handleGraspedLeft && app.handleGraspedRight) && ~app.handleBypass
                            pause(0.001); 
                        end 

                        disp('Grasp re-established... continuing trial'); 
                    end 

                    %Deliver vibration
                    if isrow(app.stim_space{ii,1})
                        stim_vector = app.stim_space{ii,1}';  % Transpose to make column
                    else
                        stim_vector = app.stim_space{ii,1};
                    end
                    
                    if currentStimSide == 0
                        stim_data = [zeros(size(stim_vector)), stim_vector];  
                        app.deliverReward();
                        sessionResults.Outcome{ii} = 'rewarded (attended side)';
                        disp('Delivering reward ...')
                    else
                        stim_data = [stim_vector, zeros(size(stim_vector))]; 
                    end   
                    
                    pause(0.001); 
                    write(app.piezoDriver, stim_data);
                    app.stim_times(ii,1) = GetSecs;
                    sessionResults.stimOnTimes{ii} = app.stim_times(ii,1);
                   
                    % % Deliver vibration
                    % if isrow(app.stim_space{ii,1})
                    %     stim_vector = app.stim_space{ii,1}';  % Transpose to make column
                    % else
                    %     stim_vector = app.stim_space{ii,1};
                    % end
                    % 
                    % if currentStimSide == 0
                    %     stim_data = [zeros(size(stim_vector)), stim_vector];  % [M x 2]
                    % else
                    %     stim_data = [stim_vector, zeros(size(stim_vector))];  % [M x 2]
                    % end
                    % write(app.piezoDriver, stim_data);
                    % app.stim_times(ii,1) = GetSecs;
                    % sessionResults.stimOnTimes{ii} = app.stim_times(ii,1);
                    % 
                    % % Monitor early lick during no-lick window
                    % tStim = GetSecs; 
                    % earlyLick = false;
                    % while GetSecs - tStim < app.ParameterData.noLickWindow
                    %     if isequal(app.SpoutLamp_2.Color, [0 1 0])
                    %         earlyLick = true;
                    %         temp = GetSecs;
                    %         break;
                    %     end
                    %     pause(0.001);
                    % end
                    % 
                    % if earlyLick
                    %     disp("Lick during no-lick window."); 
                    %     disp("Applying penalty for early lick");
                    %     sessionResults.ResponseTime{ii} = temp;
                    %     sessionResults.Outcome{ii} = 'lick during no-lick window';
                    %     sessionResults.earlyLick{ii} = true;
                    %     iti = rand() * (app.iti_end - app.iti_start) + app.iti_start;
                    %     pause(penalty_dur);
                    %     pause(iti);
                    %     continue;
                    % end
                    % 
                    % % No early lick -> Open response window
                    % disp("Response window open...");
                    % responseLick = false;
                    % tResponse = GetSecs;
                    % sessionResults.responseWindowStart{ii} = tResponse;
                    % sessionResults.earlyLick{ii} = false;
                    % 
                    % while GetSecs - tResponse < responseWindow_Dur
                    %     if isequal(app.SpoutLamp_2.Color, [0 1 0])
                    %         responseLick = true;
                    %         temp = GetSecs;
                    %         break;
                    %     end
                    %     pause(0.001);
                    % end
                    % 
                    % % Handle response
                    % sessionResults.ResponseTime{ii} = temp;
                    % if responseLick
                    %     if strcmp(app.trainingMode, 'On') && isAttended
                    %         disp("Correct response - giving reward");
                    %         app.deliverReward();
                    %         sessionResults.Outcome{ii} = 'correct';
                    %     else
                    %         disp("Response on unattended side (no reward)");
                    %         sessionResults.Outcome{ii} = 'unattended side response';
                    %     end
                    % else
                    %     disp("No response in window - no reward");
                    %     sessionResults.Outcome{ii} = 'missed window';
                    % end


                    % --- OLD SCRIPT -- 
                    % if currentStimSide == 0
                    %     %turn on the LED briefly 
                    %     writeDigitalPin(app.commChannels, 'D5', 1); %turn on LED 
                    %     write(app.andorCounter, 1); %send TTL to andorCounter
                    %     app.led_times(ii,1) = GetSecs; %record time that led was on 
                    %     sessionResults.ledTimes{ii} = app.led_times(ii,1); 
                    % 
                    %     %short delay 
                    %     timePreITI = GetSecs; 
                    %     while GetSecs < timePreITI + 0.01 %wait for a short delay of 0.01 seconds 
                    %         pause(0); 
                    %     end 
                    % 
                    %     %Turn off LED and TTL 
                    %     writeDigitalPin(app.commChannels, 'D5', 0); %turn off LED
                    %     write(app.andorCounter, 0); %stop counter 
                    % 
                    %     %Deliver stimulus to the left handle 
                    %     disp('Delivering vibration to LEFT handle');
                    %     stim_data = [zeros(size(app.stim_space{ii,1}')), app.stim_space{ii,1}'];
                    %     write(app.piezoDriver, stim_data); %Deliver stimulus 
                    %     app.stim_times(ii,1) = GetSecs; %record timing of stimulus
                    %     sessionResults.stimOnTimes{ii} = app.stim_times(ii,1); 
                    %     stimSideStr = 'left';
                    % 
                    %     %Checking if trainingMode is on and if left side is
                    %     %the attended side for this block 
                    %     if strcmp(app.trainingMode, 'On') && strcmp(attendedSide, stimSideStr)
                    % 
                    %         %Monitor early lick during reward 
                    %         tStim = GetSecs; 
                    %         earlyLick = false; 
                    %         while GetSecs - tStim < app.ParameterData.noLickWindow
                    %             if isequal(app.SpoutLamp_2.Color, [0 1 0]) %Lick detected too early 
                    %                 earlyLick = true; 
                    %                 temp = GetSecs; 
                    %                 break; 
                    %             end 
                    %             pause(0.001); 
                    %         end 
                    % 
                    %         %Early lick 
                    %         if earlyLick 
                    %             disp("Lick during no-lick window."); 
                    %             disp("Applying ppauseenalty for early lick"); 
                    %             sessionResults.ResponseTime{ii} = temp; 
                    %             sessionResults.Outcome{ii} = 'lick during no-lick window'; 
                    %             sessionResults.earlyLick{ii} = true; 
                    %             pause(penalty_dur); 
                    %             iti = rand() * (app.iti_end - app.iti_start) + app.iti_start;
                    %             pause(iti);
                    %             continue;  % skip to next trial
                    %         end 
                    % 
                    %         %No early lick -> opening response window 
                    %         disp("Response window open...");
                    %         responseLick = false;
                    %         tResponse = GetSecs;
                    %         sessionResults.responseWindowStart{ii} = tResponse; 
                    %         sessionResults.earlyLick{ii} = false; 
                    %         while GetSecs - tResponse < responseWindow_Dur
                    %             if isequal(app.SpoutLamp_2.Color, [0 1 0])
                    %                 responseLick = true;
                    %                 temp = GetSecs; 
                    %                 break;
                    %             end
                    %             pause(0.001);
                    %         end
                    % 
                    %         %Response lick 
                    %         if responseLick 
                    %             sessionResults.ResponseTime{ii} = temp; 
                    %             sessionResults.earlyLick{ii} = false; 
                    %             disp("Correct response - giving reward"); 
                    %             pause(0.001); 
                    %             app.deliverReward(); 
                    %             sessionResults.Outcome{ii} = 'correct'; 
                    %         else 
                    %             disp("No response in window - no reward"); 
                    %             temp = GetSecs; 
                    %             sessionResults.ResponseTime{ii} = temp; 
                    %             sessionResults.earlyLick{ii} = false; 
                    %             sessionResults.Outcome{ii} = 'missed window'; 
                    %         end 
                    %     end     
                    % else
                    %     %turn on the LED briefly 
                    %     writeDigitalPin(app.commChannels, 'D5', 1); %turn on LED 
                    %     write(app.andorCounter, 1); %send TTL to andorCounter
                    %     app.led_times(ii,1) = GetSecs; %record time that led was on 
                    %     sessionResults.ledTimes{ii} = app.led_times(ii,1); 
                    % 
                    %     %short delay 
                    %     timePreITI = GetSecs; 
                    %     while GetSecs < timePreITI + 0.01 %wait for a short delay of 0.01 seconds 
                    %         pause(0); 
                    %     end 
                    % 
                    %     %Turn off LED and TTL 
                    %     writeDigitalPin(app.commChannels, 'D5', 0); %turn off LED
                    %     write(app.andorCounter, 0); %stop counter 
                    % 
                    %     %Deliver stimulus to the right handle 
                    %     disp('Delivering vibration to RIGHT handle');
                    %     stim_data = [app.stim_space{ii,1}', zeros(size(app.stim_space{ii,1}'))];
                    %     write(app.piezoDriver, stim_data); %Deliver stimulus 
                    %     app.stim_times(ii,1) = GetSecs; %record timing of stimulus
                    %     sessionResults.stimOnTimes{ii} = app.stim_times(ii,1); 
                    %     stimSideStr = 'right';
                    % 
                    %     %Checking if trainingMode is on and if right side is
                    %     %the attended side for this block 
                    %     if strcmp(app.trainingMode, 'On') && strcmp(attendedSide, stimSideStr)
                    % 
                    %         %Monitor early lick during reward 
                    %         tStim = GetSecs; 
                    %         earlyLick = false; 
                    %         while GetSecs - tStim < app.ParameterData.noLickWindow
                    %             if isequal(app.SpoutLamp_2.Color, [0 1 0]) %Lick detected too early 
                    %                 earlyLick = true; 
                    %                 temp = GetSecs; 
                    %                 break; 
                    %             end 
                    %             pause(0.001); 
                    %         end 
                    % 
                    %         %Early lick 
                    %         if earlyLick 
                    %             disp("Lick during no-lick window."); 
                    %             disp("Applying penalty for early lick"); 
                    %             sessionResults.ResponseTime{ii} = temp; 
                    %             sessionResults.Outcome{ii} = 'lick during no-lick window'; 
                    %             sessionResults.earlyLick{ii} = true; 
                    %             pause(penalty_dur); 
                    %             iti = rand() * (app.iti_end - app.iti_start) + app.iti_start;
                    %             pause(iti);
                    %             continue;  % skip to next trial
                    %         end 
                    % 
                    %         %No early lick -> opening response window 
                    %         disp("Response window open...");
                    %         responseLick = false;
                    %         tResponse = GetSecs;
                    %         sessionResults.responseWindowStart{ii} = tResponse; 
                    %         sessionResults.earlyLick{ii} = false; 
                    %         while GetSecs - tResponse < responseWindow_Dur
                    %             if isequal(app.SpoutLamp_2.Color, [0 1 0])
                    %                 responseLick = true;
                    %                 temp = GetSecs; 
                    %                 break;
                    %             end
                    %             pause(0.001);
                    %         end
                    % 
                    %         %Response lick 
                    %         if responseLick 
                    %             sessionResults.ResponseTime{ii} = temp; 
                    %             sessionResults.earlyLick{ii} = false; 
                    %             disp("Correct response - giving reward"); 
                    %             pause(0.001); 
                    %             app.deliverReward(); 
                    %             sessionResults.Outcome{ii} = 'correct'; 
                    %         else 
                    %             disp("No response in window - no reward"); 
                    %             temp = GetSecs; 
                    %             sessionResults.ResponseTime{ii} = temp; 
                    %             sessionResults.earlyLick{ii} = false; 
                    %             sessionResults.Outcome{ii} = 'missed window'; 
                    %         end 
                    %     end  
                    % end 

                    %Lick during no-lick window
                    if strcmp(app.trainingMode, 'Off') 
                        pause(vibration_Dur); 
                        tNoLick = GetSecs; 
                        while GetSecs - tNoLick < noLickWindow 
                            if isequal(app.SpoutLamp_2.Color, [0 1 0])  
                                disp("Lick during no-lick window.");
                                disp('Applying penalty for early lick');
                                sessionResults.Outcome{ii} = 'lick during no-lick window';
                                sessionResults.ResponseTime{ii} = NaN;
                                sessionResults.earlyLick{ii} = true; 
                                pause(penalty_Dur);
                                iti = rand() * (app.iti_end - app.iti_start) + app.iti_start;
                                pause(iti);
                                continue;  % skip to next trial
                            end
                            pause(0.001);            
                        end 
                    end 
                    
                    %Response window
                    if strcmp(app.trainingMode, 'Off')
                        disp("Response window open...");
                        responseLick = false;
                        tResponse = GetSecs;
                        sessionResults.earlyLick{ii} = false; 
                        while GetSecs - tResponse < responseWindow_Dur
                            if isequal(app.SpoutLamp_2.Color, [0 1 0])
                                responseLick = true;
                                break;
                            end
                            pause(0.001);
                        end
                        
                        %Record response outcome and time
                        if responseLick
                            sessionResults.ResponseTime{ii} = GetSecs - tResponse;  % Store response time
                            if strcmp(attendedSide, stimSideStr)
                                disp('Correct response');
                                sessionResults.Outcome(ii) = 'correct';
                                app.deliverReward(); 
                                disp('Giving Reward :)');
                            else 
                                disp('Incorrect response');
                                sessionResults.Outcome{ii} = 'incorrect';
                                pause(penalty_Dur); 
                            end 
                        else 
                            disp('No response');
                            sessionResults.Outcome{ii} = 'no response';
                            sessionResults.ResponseTime{ii} = NaN;
                        end
                    end 
                    
                    %ITI
                    iti = rand() * (app.iti_end - app.iti_start) + app.iti_start;
                    disp(['ITI: ' num2str(iti) ' s']);

                    %Collect trial end time 
                    trialEndTime = GetSecs; %record trial end time 
                    sessionResults.trialEndTime{ii} = trialEndTime; %save trial end times 

                    %Extract lick times that happened during this trial 
                    lickTimesThisTrial = app.lickTimes(app.lickTimes >= trialStartTime & app.lickTimes <= trialEndTime); 

                    %Save lick times for this trial 
                    sessionResults.lickTimes{ii} = lickTimesThisTrial; 

                    %Check if we should advance 
                    if app.AdvanceonCorrectOnlyCheckBox.Value && ~strcmp(sessionResults.Outcome{ii}, 'correct')
                        disp('Incorrect response — repeating trial due to AdvancedOnCorrectOnly.');
                        pause(penalty_Dur); % Add penalty delay 
                    else
                        repeatTrial = false; % Either correct, or checkbox not enabled
                    end
                end 
            end 
            
            %Get experiment end time 
            app.expEndTime = GetSecs; %record end time of the session 
            sessionResults.expEndTime = app.expEndTime;     

            %Send trigger to camera to stop collecting images
            write(app.andorSignal, 0); %send TTL to camera
            pause(0.01); %Wait 10 ms
            disp('TTL sent to camera to stop collecting');

            %Send trigger to camera to close off LED ligth 
            write(app.andorLED, 0); %send TTL to camera 
            pause(0.01); %Wait 10 ms 
            disp('TTL sent to camera to turn off LED light'); 

            %Prepare save file
            timeInfo = datestr(datetime, 'dd-mmm-yyyy_HH-MM'); %i.e., 23-May-2025_10-44
            saveFileName = sprintf('%s_%s_%s_session_%s.mat', app.mouseID, app.sessionName, attendedSide, timeInfo);
            %example:
            %m000_Metadata_AttendRight_session_14-May-2025_10_44.mat 
            %timeInfo is when the beginning of recording 

            %Construct full file path 
            filePath = fullfile(app.workingdir, app.mouseID, app.currDate); 

            %Ensure folder exists; if not, create folder  
            if ~exist(filePath, 'dir')
                mkdir(filePath)
            end 
            
            %Save trial results 
            sessionResults.stimFreq = app.freq; %frequency of the stimulus
            sessionResults.stimAmp = app.amp; %amplitude of stimulus
            save(fullfile(filePath, saveFileName), 'sessionResults'); 

            disp('Experiment complete'); 
            disp(['Trial results saved to: ' fullfile(filePath, saveFileName)]); 

                    
        end

        % Callback function
        function CurrentTrialNoEditFieldValueChanged(app, event)
            app.trialNumber = app.CurrentTrialNoEditField.Value;
        end

        % Callback function
        function DurationLengthsEditFieldValueChanged(app, event)
            app.Stimulus_duration = app.DurationLengthsEditField.Value;
        end

        % Callback function
        function PreSIEditFieldValueChanged(app, event)
            app.preSI_start = app.PreSIEditField.Value; 
        end

        % Callback function
        function EditField_2ValueChanged(app, event)
            app.preSI_end = app.EditField_2.Value; 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop_flag = 1;
            app.expRunning = 0;
            app.commChannels = [];
            disp("Experiment stopped!");

            write(app.andorLED, 0); %send TTL to andor camera to turn off light 
            disp('Turning off LED light ...'); 
            app.LEDLamp.Color = 'red';  
        end

        % Callback function
        function PauseButtonPushed(app, event)

        end

        % Value changed function: BypassSwitch
        function BypassSwitchValueChanged(app, event)
            value = app.BypassSwitch.Value;
            if strcmp(value,'On')
                app.handleBypass = 1;
                app.handleGraspedLeft = 1; 
                app.handleGraspedRight = 1;
            elseif strcmp(value,'Off')
                app.handleBypass = 0;
                app.handleGraspedLeft = 0; 
                app.handleGraspedRight = 0;
            end
        end

        % Value changed function: SensorSwitch
        function SensorSwitchValueChanged(app, event)
            disp('SensorSwitch callback triggered');
            value = app.SensorSwitch.Value;
            
            if strcmp(app.SensorSwitch.Value, 'On') 
                %Set up arduino for LED 
                %Only initialize Arduino if not already done 
    
                if ~isprop(app, 'commChannels') || isempty(app.commChannels) || ~isa(app.commChannels, 'arduino') || ~isvalid(app.commChannels)
                    app.commChannels = arduino('COM4', 'Uno'); %initializes communication with Arduino board 
                    disp('Arduino initialized on COM4'); 
                else 
                    disp('Arduino already initialized'); 
                end 
    
                %Configure
                configurePin(app.commChannels, 'D2', 'DigitalOutput'); %Start signal 
                configurePin(app.commChannels, 'D3', 'DigitalOutput'); %Stop signal 
                configurePin(app.commChannels, 'D5', 'DigitalOutput'); %LED signal
                %3 flashes = experiment start 
                %1 flash = stimulus onset 
                writeDigitalPin(app.commChannels, 'D2', 0); %setting to 0V 
                writeDigitalPin(app.commChannels, 'D3', 0); %setting to 0V
                writeDigitalPin(app.commChannels, 'D5', 0); %setting to 0V 
                
                %set up piezo channels for tactile stim (will happen only
                %one time in the beginning) 
                %piezo driver that will be used for both left and right handle bars  
                app.piezoDriver = daq("ni");
                addoutput(app.piezoDriver, "Dev1", "ao0", "Voltage");
                addoutput(app.piezoDriver, "Dev1", "ao1", "Voltage"); 
                pause(0.01); 
                %ao1 = left 
                %ao0 = right 

                %set up power channel for left handle capacitive sensor 
                app.LeftcapSensPower = daq("ni"); 
                addoutput(app.LeftcapSensPower, "Dev1", "Port0/Line3", "Digital"); 
                write(app.LeftcapSensPower, 1); %turn on capactivite sensor
                pause(0.01); 
    
                %set up power channel for right handle capacitive sensor 
                app.RightcapSensPower = daq("ni"); 
                addoutput(app.RightcapSensPower, "Dev1", "Port0/Line1", "Digital"); 
                write(app.RightcapSensPower,1); %turn on capactivite sensor
                pause(0.01);

                %set up power channel for lick capacitive sensor 
                app.lickSensPower = daq("ni"); 
                addoutput(app.lickSensPower, "Dev1", "Port0/Line2", "Digital");
                write(app.lickSensPower,1); %turn on capactivite sensor 
                pause(0.01);

                %set up listener channels 
                app.capListener = daq("ni"); 
                rightListener = addinput(app.capListener, "Dev2", "ai0", "Voltage"); %right handle 
                rightListener.TerminalConfig = "SingleEnded"; 

                lickListener = addinput(app.capListener, "Dev2", "ai1", "Voltage"); %lick spout  
                lickListener.TerminalConfig = "SingleEnded"; 

                leftListener = addinput(app.capListener, "Dev2", "ai2", "Voltage"); %left handle 
                leftListener.TerminalConfig = "SingleEnded"; 
            
                %set sampling rate
                app.capListener.Rate = 5000; %Sample rate in Hz 
                app.capListener.ScansAvailableFcn = @(src, event) app.TTL_detection(src, event); 
                app.capListener.ScansAvailableFcnCount = 550; %callback triggers every 550 samples aka 110 ms
                %minimum call back value = 501 
                
                start(app.capListener, 'Continuous'); 

                app.sensorsInitialized = 1; 
                disp("Sensors Initialized"); 
   
                write(app.lickSensPower, 1); %turn on capacitive sensor 
                write(app.LeftcapSensPower, 1); %turn on capacitive sensor
                write(app.RightcapSensPower, 1); %turn on capacitive sensor
                
                %set up LED lamp  
                app.andorLED = daq("ni"); 
                addoutput(app.andorLED, "Dev1", "Port1/Line4", "Digital");
                app.LEDLamp.Color = 'red';

                disp('Sensor On'); 
            end 
    
            if strcmp(value, 'Off')
    
                write(app.lickSensPower, 0) %turn off capacitive sensor 
                write(app.LeftcapSensPower, 0) %turn off capacitive sensor 
                write(app.RightcapSensPower, 0) %turn off capacitive sensor 
                disp('Sensor Off'); 
            end 
            
            %Turn all lamps to red 
            app.SpoutLamp_2.Color = [1 0 0]; %red 
            app.RightBarGrabLamp_2.Color = [1 0 0]; %red
            app.LeftBarGrabLamp.Color = [1 0 0]; %red

        end

        % Button pushed function: LoadparameterfileButton
        function LoadparameterfileButtonPushed(app, event)
            %Open file selection dialog in current folder 
            [file, path] = uigetfile('*.mat', 'Select a parameter file', pwd); 

            %If user didn't cancel 
            if isequal(file, 0)
                %User cancled 
                app.LoadparameterfileButton.Text = 'File selection cancelled'; 
                return; 
            end 

            %Load the .mat file 
            fullpath = fullfile(path, file); 
            parameterFile = load(fullpath); 

            %Store the whole file as a struct for later use 
            app.ParameterData = parameterFile; 

            %Update button text to confirm that parameter file has been
            %loaded 
            app.LoadparameterfileButton.Text = "Loaded successfully!"; 

            %Update mouseID 
            if isfield(parameterFile, 'mouseID')
                app.MouseIDEditField_2.Value = parameterFile.mouseID;  
            else 
                app.MouseIDEditField_2.Value = 'mouseID not found'; 
            end
            
            %Update pre-stim min box 
            if isfield(parameterFile, 'prestimMin')
                app.PrestimMinEditField.Value = parameterFile.prestimMin; 
            else 
                app.PrestimMinEditField.Value = 'prestim Min not found in the parameter file'; 
            end 

            %Update pre-stim max box
            if isfield(parameterFile, 'prestimMax')
                app.PrestimMaxEditField.Value = parameterFile.prestimMax; 
            else 
                app.PrestimMaxEditField.Value = 'prestim Max not found in parameter file'; 
            end 

            %Update penalty box 
            if isfield(parameterFile, 'penalty')
                app.PenaltyEditField.Value = parameterFile.penalty; 
            else 
                app.PenaltyEditField.Value = 'penalty not found in parameter file'; 
            end 

            %Update target bias box 
            if isfield(parameterFile, 'targetBias')
                app.TargetBiasEditField.Value = parameterFile.targetBias; 
            else 
                app.TargetBiasEditField.Value = 'target bias not found in parameter file'; 
            end 

            %Generate random attention assignment 
            %Which handle would be considered the "attended handle" for
            %that particular block 
            
            rng('shuffle'); %ensure different randomization each time experiment starts 
            halfBlockNumber = app.ParameterData.numBlocks/2; 
            app.attnAssign = [zeros(halfBlockNumber, 1); ones(halfBlockNumber, 1)]; %create an array of half 0s and 1s 

            app.attnAssign = app.attnAssign(randperm(length(app.attnAssign))); %scramble the order of the 0s and 1s 
            
        end

        % Callback function
        function AcquisitionModeDropDownValueChanged(app, event)
            selected = app.AcquisitionModeDropDown.Value; 
            disp(['You selected: ', selected]); 
            %Options
            %Single scan: taking one image 
            %Kinetic series: a fixed number of user frames are taken per
            %trigger
            %Accumulate: multiple images are taken and summed together into
            %one image 
        end

        % Callback function
        function ConnecttoCameraButtonPushed(app, event)
            AT_InitialiseLibrary(); %Initialize SDK  
            hCamPtr = libpointer('int32Ptr', 0); %Create a pointer to store the camera handle
            %aka location of the camera handle 
            %camera handle = unique identifier for the camera 
            calllib('atcore', 'AT_Open', 0, hCamPtr); %Open the camera 
            hCam = hCamPtr.Value; %store handle properly 
            app.hCam = hCam; 
        end

        % Callback function
        function LoadButtonPushed(app, event)

            %Exposure time 
            %how long the camera sensor collects light for each image
            exposureTime = app.ExposureTimesEditField.Value; %in seconds 
            AT_SetFloat(app.hCam, 'ExposureTime', exposureTime); 

            %Set trigger mode from dropdown 
            triggerMode = app.TriggerModeDropDown.Value; 
            AT_SetEnumString(app.hCam, 'TriggerMode', triggerMode); 

            %Set acquisition mode 
            acqMode = app.AcquisitionModeDropDown.Value; 
            AT_SetEnumString(app.hCam, 'AcquisitionMode', acqMode); 

            %Set Kinetic Series Length
            kineticLength = app.KineticSeriesLengthEditField.Value;
            AT_SetInt(app.hCam, 'KineticCycleLength', kineticLength);
        
            %Set Number of Accumulations
            numAccum = app.NumberOfAccumulationsEditField.Value;
            AT_SetInt(app.hCam, 'AccumulateCycleCount', numAccum);
        
            %Query and display resulting frame rate ---
            frameRatePtr = libpointer('doublePtr', 0);
            AT_GetFloat(app.hCam, 'FrameRate', frameRatePtr);
            actualFrameRate = frameRatePtr.Value;
            disp('All parameters loaded to camera'); 
            disp(['Actual frame rate: ', num2str(actualFrameRate), ' fps']);
            
        end

        % Callback function
        function TriggerModeDropDownValueChanged(app, event)
            selected = app.AcquisitionModeDropDown.Value; 
            disp(['You selected: ', selected]); 
            %Options
            %Internal Trigger: camera controls when to exposure based on
            %interal timing 
            %External Trigger: camera waits for an external source (another
            %device or TTL pulse) to start each exposure 
            %Software Trigger: Trigger comes from a MATLAB script or
            %software 
            %External Start: camera waits for one external signal to begin
            %full acquisition series
            %External Exposure Trigger: external signal controls both
            %exposure timing and spacing 
        end

        % Callback function
        function TriggerSourceDropDownValueChanged(app, event)
            selected = app.AcquisitionModeDropDown.Value; 
            disp(['You selected: ', selected]); 
        end

        % Value changed function: PauseSwitch
        function PauseSwitchValueChanged(app, event)
            value = app.PauseSwitch.Value;
            if strcmp(value, 'On')
                app.pauseFlag = 1; %pause
                write(app.andorLED, 0); %send TTL to andor to turn off LED
                pause(0.01); %Wait 10 ms
                disp('Turning off LED light ...'); 
                app.LEDLamp.Color = 'red';  

            else 
                app.pauseFlag = 0; %running
                write(app.andorLED, 1); %send TTL to andor to turn on LED
                pause(0.01); %Wait 10 ms
                disp('Turning on LED light ...'); 
                app.LEDLamp.Color = 'green';  
            end 
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            %Stop and delete capListener if it exists
            if isfield(app, 'capListener') && ~isempty(app.capListener) && isvalid(app.capListener)
                try
                    stop(app.capListener);
                    delete(app.capListener);
                catch ME
                    warning('Error stopping or deleting capListener: %s', ME.message);
                end
            end
        
            %Close valves and delete fluidReward if it exists
            if isfield(app, 'fluidReward') && ~isempty(app.fluidReward) && isvalid(app.fluidReward)
                try
                    % Close solenoids
                    write(app.fluidReward, [0 0]);
                    pause(0.01); % Let it finish writing
                    delete(app.fluidReward);
                catch ME
                    warning('Error writing or deleting fluidReward: %s', ME.message);
                end
            end

            %Confirm successful cleanup 
            disp('Successfully closed everything... have a good day :)')

            %Close gui 
            close(app.UIFigure); 
        end

        % Button pushed function: GiveRewardButton
        function GiveRewardButtonPushed(app, event)
            %Initialize reward system if not done already 
            if app.rewardInitialize == 0 
                app.initializeReward(); 
                app.rewardInitialize = 1; 
            end
            
            %Deliver reward 
            app.deliverReward(); 
            disp('Giving Reward :)'); 
        end

        % Value changed function: LiquidSolenoidSwitch
        function LiquidSolenoidSwitchValueChanged(app, event)
            %Initialize reward system if not done already 
            if app.rewardInitialize == 0 
                app.initializeReward(); 
                app.rewardInitialize = 1; 
                disp('Reward system initialized');
            end 
            
            %Check if toggle is ON or OFF 
            disp(['Switch value: ', num2str(app.LiquidSolenoidSwitch.Value)]);
            if strcmp(app.LiquidSolenoidSwitch.Value, "On")
                %Open liquid solenoid and keep it open until toggle is
                %switched off 
                write(app.fluidReward, [1 0]); %[fluid ON, vacuum OFF]
                disp('Liquid solenoid ON'); 
            else 
                write(app.fluidReward, [0 0]); %[fluid OFF, vacuum OFF] 
                disp('Liquid solenoid OFF'); 
            end 
            
        end

        % Value changed function: VacuumSolenoidSwitch
        function VacuumSolenoidSwitchValueChanged(app, event)
            %Initialize reward system if not done already 
            if app.rewardInitialize == 0 
                app.initializeReward(); disp(['Switch value: ', num2str(app.LiquidSolenoidSwitch.Value)]);
                app.rewardInitialize = 1; 
            end 
            
            %Check if toggle is ON or OFF 
            disp(['Switch value: ', num2str(app.VacuumSolenoidSwitch.Value)]);
            if strcmp(app.VacuumSolenoidSwitch.Value, "On") 
                %Open liquid solenoid and keep it open until toggle is
                %switched off 
                write(app.fluidReward, [0 1]); %[fluid OFF, vacuum ON]
                disp('Vacuum solenoid ON'); 
            else 
                write(app.fluidReward, [0 0]); %[fluid OFF, vacuum OFF] 
                disp('Vacuum solenoid OFF')
            end 
        end

        % Value changed function: TrainingModeSwitch
        function TrainingModeSwitchValueChanged(app, event)
            value = app.TrainingModeSwitch.Value;
            if strcmp(value, 'On')
                disp('Training Mode ON'); 
                sessionResults.trainingMode = 'on'; 
                app.trainingMode = true;
            else
                disp('Training Mode OFF'); 
                sessionResults.trainingMode = 'off'; 
                app.trainingMode = false;
            end
        end

        % Value changed function: VoltageRampSwitch
        function VoltageRampSwitchValueChanged(app, event)
            value = app.VoltageRampSwitch.Value;
            if strcmp(value, 'On')
                disp('Voltage Ramp ON'); 
                sessionResults.voltageRamp = 'on'; 
                app.trainingMode = true;
            else
                disp('Voltage Ramp OFF'); 
                sessionResults.voltageRamp = 'off'; 
                app.trainingMode = false;
            end
        end

        % Value changed function: PrestimMinEditField
        function PrestimMinEditFieldValueChanged(app, event)
            value = app.PrestimMinEditField.Value;
            
        end

        % Value changing function: MouseIDEditField_2
        function MouseIDEditField_2ValueChanging(app, event)

        end

        % Button pushed function: UpdateSpoutButton
        function UpdateSpoutButtonPushed(app, event)
            app.lickSpout = 0; 
            app.SpoutLamp_2.Color = [1, 0, 0];
        end

        % Value changed function: TargetBiasEditField
        function TargetBiasEditFieldValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MouseForepawStimulatorLabel = uilabel(app.UIFigure);
            app.MouseForepawStimulatorLabel.Text = ' Mouse Forepaw Stimulator ';
            app.ExperimentPanel = uipanel(app.UIFigure);
            app.ExperimentPanel.Title = 'Experiment';
            app.LoadparameterfileButton = uibutton(app.ExperimentPanel, 'push');
            app.LoadparameterfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparameterfileButtonPushed, true);
            app.LoadparameterfileButton.Text = 'Load parameter file';
            app.CurrentTrialNoEditField_3Label = uilabel(app.ExperimentPanel);
            app.CurrentTrialNoEditField_3Label.Text = 'Current Trial No.';
            app.CurrentTrialNoEditField_3 = uieditfield(app.ExperimentPanel, 'numeric');
            app.RewardhandleLabel = uilabel(app.ExperimentPanel);
            app.RewardhandleLabel.Text = 'Reward handle';
            app.PreLoadStimuliButton = uibutton(app.ExperimentPanel, 'push');
            app.PreLoadStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @PreLoadStimuliButtonPushed, true);
            app.PreLoadStimuliButton.Text = 'Pre-Load Stimuli';
            app.MouseIDEditField_2Label = uilabel(app.ExperimentPanel);
            app.MouseIDEditField_2Label.Text = 'Mouse ID';
            app.MouseIDEditField_2 = uieditfield(app.ExperimentPanel, 'text');
            app.MouseIDEditField_2.ValueChangingFcn = createCallbackFcn(app, @MouseIDEditField_2ValueChanging, true);
            app.AdvanceonCorrectOnlyCheckBox = uicheckbox(app.ExperimentPanel);
            app.AdvanceonCorrectOnlyCheckBox.Text = 'Advance on Correct Only';
            app.BlockLabel = uilabel(app.ExperimentPanel);
            app.BlockLabel.Text = 'Block #';
            app.BlockEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.PrestimMinEditFieldLabel = uilabel(app.ExperimentPanel);
            app.PrestimMinEditFieldLabel.Text = 'Prestim Min';
            app.PrestimMinEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.PrestimMinEditField.ValueChangedFcn = createCallbackFcn(app, @PrestimMinEditFieldValueChanged, true);
            app.PrestimMaxEditFieldLabel = uilabel(app.ExperimentPanel);
            app.PrestimMaxEditFieldLabel.Text = 'Prestim Max';
            app.PrestimMaxEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.PenaltyEditFieldLabel = uilabel(app.ExperimentPanel);
            app.PenaltyEditFieldLabel.Text = 'Penalty';
            app.PenaltyEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.TargetBiasEditFieldLabel = uilabel(app.ExperimentPanel);
            app.TargetBiasEditFieldLabel.Text = 'Target Bias';
            app.TargetBiasEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.TargetBiasEditField.ValueChangedFcn = createCallbackFcn(app, @TargetBiasEditFieldValueChanged, true);
            app.RewardPanel = uipanel(app.UIFigure);
            app.RewardPanel.Title = 'Reward';
            app.GiveRewardButton = uibutton(app.RewardPanel, 'push');
            app.GiveRewardButton.ButtonPushedFcn = createCallbackFcn(app, @GiveRewardButtonPushed, true);
            app.GiveRewardButton.Text = 'Give Reward';
            app.LiquidSolenoidSwitchLabel = uilabel(app.RewardPanel);
            app.LiquidSolenoidSwitchLabel.Text = 'Liquid Solenoid';
            app.LiquidSolenoidSwitch = uiswitch(app.RewardPanel, 'slider');
            app.LiquidSolenoidSwitch.ValueChangedFcn = createCallbackFcn(app, @LiquidSolenoidSwitchValueChanged, true);
            app.VacuumSolenoidSwitchLabel = uilabel(app.RewardPanel);
            app.VacuumSolenoidSwitchLabel.Text = 'Vacuum Solenoid';
            app.VacuumSolenoidSwitch = uiswitch(app.RewardPanel, 'slider');
            app.VacuumSolenoidSwitch.ValueChangedFcn = createCallbackFcn(app, @VacuumSolenoidSwitchValueChanged, true);
            app.TrainingModeSwitchLabel = uilabel(app.RewardPanel);
            app.TrainingModeSwitchLabel.Text = 'Training Mode';
            app.TrainingModeSwitch = uiswitch(app.RewardPanel, 'slider');
            app.TrainingModeSwitch.ValueChangedFcn = createCallbackFcn(app, @TrainingModeSwitchValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.BypassSwitch = uiswitch(app.Panel, 'slider');
            app.BypassSwitch.ValueChangedFcn = createCallbackFcn(app, @BypassSwitchValueChanged, true);
            app.BypassSwitchLabel = uilabel(app.Panel);
            app.BypassSwitchLabel.Text = 'Bypass';
            app.SensorSwitch = uiswitch(app.Panel, 'slider');
            app.SensorSwitch.ValueChangedFcn = createCallbackFcn(app, @SensorSwitchValueChanged, true);
            app.SensorSwitchLabel = uilabel(app.Panel);
            app.SensorSwitchLabel.Text = 'Sensor';
            app.VoltageRampSwitch = uiswitch(app.Panel, 'slider');
            app.VoltageRampSwitch.ValueChangedFcn = createCallbackFcn(app, @VoltageRampSwitchValueChanged, true);
            app.VoltageRampSwitchLabel = uilabel(app.Panel);
            app.VoltageRampSwitchLabel.Text = 'Voltage Ramp';
            app.LeftBarGrabLampLabel = uilabel(app.Panel);
            app.LeftBarGrabLampLabel.Text = 'Left Bar Grab';
            app.LeftBarGrabLamp = uilamp(app.Panel);
            app.RightBarGrabLamp_2Label = uilabel(app.Panel);
            app.RightBarGrabLamp_2Label.Text = 'Right Bar Grab';
            app.RightBarGrabLamp_2 = uilamp(app.Panel);
            app.SpoutLamp_2Label = uilabel(app.Panel);
            app.SpoutLamp_2Label.Text = 'Spout';
            app.SpoutLamp_2 = uilamp(app.Panel);
            app.LEDLampLabel = uilabel(app.Panel);
            app.LEDLampLabel.Text = 'LED';
            app.LEDLamp = uilamp(app.Panel);
            app.UpdateSpoutButton = uibutton(app.Panel, 'push');
            app.UpdateSpoutButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSpoutButtonPushed, true);
            app.UpdateSpoutButton.Text = 'Update Spout';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PauseSwitchLabel = uilabel(app.UIFigure);
            app.PauseSwitchLabel.Text = 'Pause';
            app.PauseSwitch = uiswitch(app.UIFigure, 'slider');
            app.PauseSwitch.ValueChangedFcn = createCallbackFcn(app, @PauseSwitchValueChanged, true);
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mouse_forepaw_stimulator_v6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end